classdef auto_corr_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GenerateDataTab                 matlab.ui.container.Tab
        TransMat                        matlab.ui.control.Table
        TransitionRateMatrixflowsLabel  matlab.ui.control.Label
        LengthofTracessLabel            matlab.ui.control.Label
        LengthTraceNum                  matlab.ui.control.Label
        Rates                           matlab.ui.control.Table
        EmissionRatesRNAsLabel          matlab.ui.control.Label
        BootstrapsCheckBox              matlab.ui.control.CheckBox
        InitProbs                       matlab.ui.control.Table
        InitialProbabilityDistributionLabel  matlab.ui.control.Label
        TimeResolutionsEditFieldLabel   matlab.ui.control.Label
        TimeResolutionsEditField        matlab.ui.control.NumericEditField
        LengthofTracespointsEditFieldLabel  matlab.ui.control.Label
        LengthofTracespointsEditField   matlab.ui.control.NumericEditField
        NumberofTracesEditFieldLabel    matlab.ui.control.Label
        NumberofTracesEditField         matlab.ui.control.NumericEditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofStatesEditField         matlab.ui.control.NumericEditField
        fluoRNAEditFieldLabel           matlab.ui.control.Label
        fluoRNAEditField                matlab.ui.control.NumericEditField
        GenerateListBoxLabel            matlab.ui.control.Label
        GenerateListBox                 matlab.ui.control.ListBox
        NoiseEditFieldLabel             matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        MaxDelayEditFieldLabel          matlab.ui.control.Label
        MaxDelayEditField               matlab.ui.control.NumericEditField
        CutEditFieldLabel               matlab.ui.control.Label
        CutEditField                    matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        ConstantTab                     matlab.ui.container.Tab
        ElongationTimesEditFieldLabel   matlab.ui.control.Label
        ElongationTimesEditField        matlab.ui.control.NumericEditField
        RiseTimesEditFieldLabel         matlab.ui.control.Label
        RiseTimesEditField              matlab.ui.control.NumericEditField
        DiffRatesTab                    matlab.ui.container.Tab
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        StandardDeviationEditField      matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        GaussianLabel                   matlab.ui.control.Label
        UniformLabel                    matlab.ui.control.Label
        BeginEditFieldLabel             matlab.ui.control.Label
        BeginEditField                  matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        StepEditField_3Label            matlab.ui.control.Label
        StepEditField_3                 matlab.ui.control.NumericEditField
        RiseTimeLocEditFieldLabel       matlab.ui.control.Label
        RiseTimeLocEditField            matlab.ui.control.NumericEditField
        DiffLenTab                      matlab.ui.container.Tab
        GaussianLabel_2                 matlab.ui.control.Label
        UniformLabel_2                  matlab.ui.control.Label
        DropDown_2Label                 matlab.ui.control.Label
        DropDown_2                      matlab.ui.control.DropDown
        StandardDeviationEditField_2Label  matlab.ui.control.Label
        StandardDeviationEditField_2    matlab.ui.control.NumericEditField
        MeanEditField_2Label            matlab.ui.control.Label
        MeanEditField_2                 matlab.ui.control.NumericEditField
        BeginEditField_2Label           matlab.ui.control.Label
        BeginEditField_2                matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        EndEditField_2                  matlab.ui.control.NumericEditField
        StepEditField_4Label            matlab.ui.control.Label
        StepEditField_4                 matlab.ui.control.NumericEditField
        RiseTimeEditFieldLabel          matlab.ui.control.Label
        RiseTimeEditField               matlab.ui.control.NumericEditField
        PausesTab                       matlab.ui.container.Tab
        ElongationTimewoPausesEditFieldLabel  matlab.ui.control.Label
        ElongationTimewoPausesEditField  matlab.ui.control.NumericEditField
        RiseTimeLocEditField_2Label     matlab.ui.control.Label
        RiseTimeLocEditField_2          matlab.ui.control.NumericEditField
        NumberofPausesEditFieldLabel    matlab.ui.control.Label
        NumberofPausesEditField         matlab.ui.control.NumericEditField
        PauseLocs                       matlab.ui.control.Table
        MeanPauses                      matlab.ui.control.Table
        SDPauses                        matlab.ui.control.Table
        PauseLocationsLabel             matlab.ui.control.Label
        MeanPauseTimesLabel             matlab.ui.control.Label
        SDPauseTimesLabel               matlab.ui.control.Label
        ElongationDynamicsLabel         matlab.ui.control.Label
        ElongationDynamicsChoiceListBoxLabel  matlab.ui.control.Label
        ElongationDynamicsChoiceListBox  matlab.ui.control.ListBox
        MatrixProperties                matlab.ui.control.Table
        PlotExpectationValueCheckBox    matlab.ui.control.CheckBox
        UseDiffTracesCheckBox           matlab.ui.control.CheckBox
        ShowElongandRiseTimesCheckBox   matlab.ui.control.CheckBox
        Learning1Tab                    matlab.ui.container.Tab
        GuessElongationTimeCheckBox     matlab.ui.control.CheckBox
        UseRealParamsCheckBox           matlab.ui.control.CheckBox
        ElongationTimeGuessesLabel      matlab.ui.control.Label
        MinimumEditFieldLabel           matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        RiseTimeGuessesLabel            matlab.ui.control.Label
        MinimumEditField_2Label         matlab.ui.control.Label
        MinimumEditField_2              matlab.ui.control.NumericEditField
        MaximumEditField_2Label         matlab.ui.control.Label
        MaximumEditField_2              matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        StepEditField_2                 matlab.ui.control.NumericEditField
        NumberofStatesEditField_2Label  matlab.ui.control.Label
        NumberofStatesEditField_2       matlab.ui.control.NumericEditField
        TransMatTest                    matlab.ui.control.Table
        TransitionRateMatrixflowsLabel_2  matlab.ui.control.Label
        fluoRNAEditField_2Label         matlab.ui.control.Label
        fluoRNAEditField_2              matlab.ui.control.NumericEditField
        RatesTest                       matlab.ui.control.Table
        EmissionRatesRNAsLabel_2        matlab.ui.control.Label
        InitProbsTest                   matlab.ui.control.Table
        InitialProbabilityDistributionLabel_2  matlab.ui.control.Label
        ElongationTimeEstimateEditFieldLabel  matlab.ui.control.Label
        ElongationTimeEstimateEditField  matlab.ui.control.NumericEditField
        RiseTimeEstimateEditFieldLabel  matlab.ui.control.Label
        RiseTimeEstimateEditField       matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        ErrorsLabel                     matlab.ui.control.Label
        Learning2Tab                    matlab.ui.container.Tab
        GuessElongationTimeCheckBox_2   matlab.ui.control.CheckBox
        Equationacbx1aelong_termelongationtimerisetimeLabel  matlab.ui.control.Label
        InitialGuessesLabel             matlab.ui.control.Label
        aminimumEditFieldLabel          matlab.ui.control.Label
        aminimumEditField               matlab.ui.control.NumericEditField
        bminimumEditFieldLabel          matlab.ui.control.Label
        bminimumEditField               matlab.ui.control.NumericEditField
        bmaximumEditFieldLabel          matlab.ui.control.Label
        bmaximumEditField               matlab.ui.control.NumericEditField
        ResultsLabel                    matlab.ui.control.Label
        elongationtimeEditField_2Label  matlab.ui.control.Label
        elongationtimeEditField_2       matlab.ui.control.NumericEditField
        risetimeEditField_2Label        matlab.ui.control.Label
        risetimeEditField_2             matlab.ui.control.NumericEditField
        amaximumEditFieldLabel          matlab.ui.control.Label
        amaximumEditField               matlab.ui.control.NumericEditField
        elongationtimeminimumEditFieldLabel  matlab.ui.control.Label
        elongationtimeminimumEditField  matlab.ui.control.NumericEditField
        elongationtimemaximumEditFieldLabel  matlab.ui.control.Label
        elongationtimemaximumEditField  matlab.ui.control.NumericEditField
        risetimepercentageminimumEditFieldLabel  matlab.ui.control.Label
        risetimepercentageminimumEditField  matlab.ui.control.NumericEditField
        risetimepercentagemaximumEditFieldLabel  matlab.ui.control.Label
        risetimepercentagemaximumEditField  matlab.ui.control.NumericEditField
        NumberofEigenvaluesEditFieldLabel  matlab.ui.control.Label
        NumberofEigenvaluesEditField    matlab.ui.control.NumericEditField
        UITable2                        matlab.ui.control.Table
        TypeofFittingButtonGroup        matlab.ui.container.ButtonGroup
        NormalFittingButton             matlab.ui.control.RadioButton
        GaussFittingButton              matlab.ui.control.RadioButton
        ElongationDistributionPars      matlab.ui.control.Table
        ExtraParameterResultsLabel      matlab.ui.control.Label
        ExtraParamResults               matlab.ui.control.Table
        risetimepercentageinitialEditFieldLabel  matlab.ui.control.Label
        RiseTimeGuess                   matlab.ui.control.NumericEditField
        elongationtimeinitialEditFieldLabel  matlab.ui.control.Label
        ElongationTimeGuess             matlab.ui.control.NumericEditField
        DynamicsGuesses                 matlab.ui.control.Table
        ExtraParamsGuesses              matlab.ui.control.Table
        UseMCMCModelCheckBox            matlab.ui.control.CheckBox
        MakeHeatMapCheckBox             matlab.ui.control.CheckBox
        MesswithTracesTab               matlab.ui.container.Tab
        Extend0sCheckBox                matlab.ui.control.CheckBox
        MeanExtendedEditFieldLabel      matlab.ui.control.Label
        MeanExtendedEditField           matlab.ui.control.NumericEditField
        DropRandomLowPointsCheckBox     matlab.ui.control.CheckBox
        MaxValueDroppedEditFieldLabel   matlab.ui.control.Label
        MaxValueDroppedEditField        matlab.ui.control.NumericEditField
        ProbabilityDroppedEditFieldLabel  matlab.ui.control.Label
        ProbabilityDroppedEditField     matlab.ui.control.NumericEditField
        StdExtendedEditFieldLabel       matlab.ui.control.Label
        StdExtendedEditField            matlab.ui.control.NumericEditField
        EliminateExtraZerosCheckBox     matlab.ui.control.CheckBox
        MaximumConsecutiveZerosEditFieldLabel  matlab.ui.control.Label
        MaximumConsecutiveZerosEditField  matlab.ui.control.NumericEditField
        DivideTracesWithZerosCheckBox   matlab.ui.control.CheckBox
        MinimumConsecutiveZerosEditFieldLabel  matlab.ui.control.Label
        MinimumConsecutiveZerosEditField  matlab.ui.control.NumericEditField
        MinimumZeroValEditFieldLabel    matlab.ui.control.Label
        MinimumZeroValEditField         matlab.ui.control.NumericEditField
        MinTraceLengthEditFieldLabel    matlab.ui.control.Label
        MinTraceLengthEditField         matlab.ui.control.NumericEditField
        CutLengthEditFieldLabel         matlab.ui.control.Label
        CutLengthEditField              matlab.ui.control.NumericEditField
        GeneratePlotsButton             matlab.ui.control.Button
        LoadPlotsButton                 matlab.ui.control.Button
    end


    methods (Access = private)
    
        function learn1(app,auto_cor)
            
            time_res = app.TimeResolutionsEditField.Value;
            points_per_trace = app.LengthofTracespointsEditField.Value;
            num_traces = app.NumberofTracesEditField.Value;
            
            elong_min = app.MinimumEditField.Value;
            elong_max = app.MaximumEditField.Value;
            elong_step = app.StepEditField.Value;
            rise_min = app.MinimumEditField_2.Value;
            rise_max = app.MaximumEditField_2.Value;
            rise_step = app.StepEditField_2.Value;
            if app.UseRealParamsCheckBox.Value
                num_states = app.NumberofStatesEditField.Value;
                trans_mat = app.TransMat.Data;
                rna_per_sec = app.Rates.Data;
                fluo_per_rna = app.fluoRNAEditField.Value;
                init_dist = app.InitProbs.Data;                
            else
                num_states = app.NumberofStatesEditField_2.Value;
                trans_mat = app.TransMatTest.Data;
                rna_per_sec = app.RatesTest.Data;
                fluo_per_rna = app.fluoRNAEditField_2.Value;
                init_dist = app.InitProbsTest.Data;
            end
            [elong,rise,all] = grid_monte_carlo(time_res, points_per_trace, num_traces, num_states, ...
                          trans_mat, rna_per_sec, fluo_per_rna, init_dist, auto_cor, elong_min, ...
                          elong_max, elong_step, rise_min, rise_max, rise_step);
            app.ElongationTimeEstimateEditField.Value = elong;
            app.RiseTimeEstimateEditField.Value = rise;
            rows = elong_min:elong_step:elong_max;
            cols = rise_min:rise_step:rise_max;
            app.UITable.RowName = rows;
            app.UITable.ColumnName = cols;
            app.UITable.Data = all;
        end
        
        function learn2(app,auto_cor,hs)

            eigs = app.NumberofEigenvaluesEditField.Value;
            is_gauss = app.GaussFittingButton.Value;
            upper = [app.elongationtimemaximumEditField.Value, app.risetimepercentagemaximumEditField.Value, ...
                ones(1,eigs) * app.amaximumEditField.Value, ones(1,eigs) * app.bmaximumEditField.Value];
            lower = [app.elongationtimeminimumEditField.Value, app.risetimepercentageminimumEditField.Value, ...
                ones(1,eigs) * app.aminimumEditField.Value, ones(1,eigs) * app.bminimumEditField.Value];
            x0 = [app.ElongationTimeGuess.Value, app.RiseTimeGuess.Value, app.DynamicsGuesses.Data(:,1)', ...
                app.DynamicsGuesses.Data(:,2)'];
            
            if app.UseMCMCModelCheckBox.Value
                priors = zeros(1, 8); % elong, a, b, rise, and their spreads
                priors(1) = x0(1);
                priors(2) = (upper(1) - lower(1)) * 8;
                priors(3) = x0(2);
                priors(4) = (upper(2) - lower(2)) * 8;
                priors(5) = mean(x0(3:2 + eigs));
                priors(6) = (upper(3) - lower(3)) * 8;
                priors(7) = mean(x0(3 + eigs:end));
                priors(8) = (upper(end) - lower(end)) * 8;
                x = matlab_hmc(auto_cor,x0,priors,eigs,hs);
                
            else
                fitting_func = @decay_line_fit2;
                if is_gauss
                    dynam_vars = app.ElongationDistributionPars.Data;
                    x0 = [x0 app.ExtraParamsGuesses.Data];
                    upper = [upper dynam_vars(2) dynam_vars(4)];
                    lower = [lower dynam_vars(1), dynam_vars(3)];
                    fitting_func = @gauss_fit;
                end  
                    
                if ~isempty(hs)
                    x = fitting_func(auto_cor, x0, upper, lower,hs);
                else
                    x = fitting_func(auto_cor, x0, upper, lower);
                end
                
                if app.MakeHeatMapCheckBox.Value
                    create_error_heatmap(auto_cor, upper, lower);
                end
                
            end

            eigs_results = zeros(eigs,2);
            eigs_results(:,1) = x(3:2 + eigs);
            eigs_results(:,2) = x(3 + eigs:2 + 2 * eigs);
            app.UITable2.Data = eigs_results;
            app.elongationtimeEditField_2.Value = x(1);
            app.risetimeEditField_2.Value = x(2);
            if is_gauss
                app.ExtraParamResults.Data = [x(end-1), x(end)];
            end
        end
        
        function new_traces = messWithTraces(app, traces)
            min_val = app.MinimumZeroValEditField.Value;
            new_traces = {};
            idx = 1;
            for i = 1:length(traces)
                trace = traces{i};
                if app.Extend0sCheckBox.Value
                    split = find(trace < min_val);
                    split_diff = split(2:end) - split(1:end-1);
                    split = split(split_diff > 1);
                    for j = 1:length(split)
                        to_add = int16(normrnd(app.MeanExtendedEditField.Value, app.StdExtendedEditField.Value));
                        to_add = double(to_add);
                        if to_add > 0
                            trace = [trace(1:split(j)) zeros(1, to_add) trace(split(j):end)];
                            split = split + to_add;
                        end
                    end
                end
                
                if app.DropRandomLowPointsCheckBox.Value
                    low_pts = find(trace > 0 & trace < app.MaxValueDroppedEditField.Value);
                    for pt = low_pts
                        if rand() < app.ProbabilityDroppedEditField.Value
                            trace(pt) = 0;
                        end
                    end
                end
                
                if app.EliminateExtraZerosCheckBox.Value
                    non_zero_pts = [1 find(trace > min_val) length(trace)];
                    non_zero_pts_diff = non_zero_pts(2:end) - non_zero_pts(1:end-1);
                    extended_zeros = find(non_zero_pts_diff > app.MaximumConsecutiveZerosEditField.Value);
                    for j = flip(extended_zeros)
                        trace = [trace(1:non_zero_pts(j) + app.MaximumConsecutiveZerosEditField.Value) ...
                            trace(non_zero_pts(j+1):end)];
                    end
                end
                
                if app.DivideTracesWithZerosCheckBox.Value
                    min_trace = app.MinTraceLengthEditField.Value;
                    cut = app.CutLengthEditField.Value;
                    non_zero_pts = find(trace > min_val);
                    non_zero_pts_diff = non_zero_pts(2:end) - non_zero_pts(1:end-1);
                    extended_zeros = find(non_zero_pts_diff > app.MinimumConsecutiveZerosEditField.Value);
                    
                    for j = 2:length(extended_zeros)
                        if non_zero_pts(extended_zeros(j)) - non_zero_pts(extended_zeros(j-1) + 1) > ...
                                min_trace + 2 * cut
                            new_traces{idx} = trace(non_zero_pts(extended_zeros(j-1) + 1) +...
                                cut:non_zero_pts(extended_zeros(j)) - cut);
                            idx = idx + 1;
                        end
                    end
                else              
                    new_traces{idx} = trace;
                    idx = idx + 1;
                end
            end
        end       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TransMat.Data = [-0.0115,  0.0095,  0.0000; ...
                                 0.0115, -0.0180,  0.0440; ...
                                 0.0000,  0.0085, -0.0440];
            app.Rates.Data = [.001,.2,.4];
            app.InitProbs.Data = [1,0,0];
            app.ElongationDistributionPars.Data = [0, 2, 0, 1];
            
            %says what the a and b values are for the matrix
            addpath('src/');
            addpath('src/utilities/');
            [a_arr, b_arr] = decompose_matrix(app.TransMat.Data, app.Rates.Data);
            mat_prop_dat = zeros(length(app.Rates.Data), 2);
            mat_prop_dat(:, 1) = a_arr';
            mat_prop_dat(:, 2) = b_arr';
            app.MatrixProperties.Data = mat_prop_dat;
            
            app.TransMatTest.Data = app.TransMat.Data;
            app.RatesTest.Data = app.Rates.Data;
            app.InitProbsTest.Data = app.InitProbs.Data;
            
            app.PauseLocs.Data = .5;
            app.MeanPauses.Data = 20;
            app.SDPauses.Data = 5;
            
            app.DynamicsGuesses.Data = [1.8, .38];
            app.ExtraParamsGuesses.Data = [0, 1];
        end

        % Value changed function: NumberofStatesEditField
        function NumberofStatesEditFieldValueChanged(app, event)
            value = app.NumberofStatesEditField.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.TransMat.ColumnName = cols;
            app.TransMat.Data = abs(normrnd(0,.1,[value,value]));
            for v = 1:value
                app.TransMat.Data(v,v) = 0;
                app.TransMat.Data(v,v) = -sum(app.TransMat.Data(:,v));
            end
            
            %resets emission rates table
            app.Rates.ColumnName = cols;
            dat = random('uniform',0,1,[1,value-1]);
            app.Rates.Data = [.0001, sort(dat)];
            
            %resets intial probability distribution table
            app.InitProbs.ColumnName = cols;
            dat = random('uniform',0,1,[1,value]);
            dat = dat / sum(dat);
            app.InitProbs.Data = dat;
            
            %says what the new a and b values are for the new matrix
            [a_arr, b_arr] = decompose_matrix(app.TransMat.Data, app.Rates.Data);
            mat_prop_dat = zeros(value, 2);
            mat_prop_dat(:, 1) = a_arr';
            mat_prop_dat(:, 2) = b_arr';
            app.MatrixProperties.Data = mat_prop_dat;
            
        end

        % Value changed function: LengthofTracespointsEditField
        function LengthofTracespointsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Value changed function: TimeResolutionsEditField
        function TimeResolutionsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Button pushed function: LoadPlotsButton
        function LoadPlotsButtonPushed(app, event)
            [filename,pathname] = uigetfile;
            filepath = [pathname filename];
            fig = openfig(filepath);
            h = findobj(gca, 'Type', 'line');
            if isempty(h)
                h = findobj(gca, 'Type', 'errorbar');
            end
            auto_cor = get(h, 'Ydata');
            close(fig);
            
            %makes sure things are normalized
            auto_cor = auto_cor / auto_cor(1);
            %plots the imported data's correlation functions
            cor_ops = app.GenerateListBox.Value;
            hs = [];
            if ismember('Autocorrelation',cor_ops)
                h = figure();
                plot(0:length(auto_cor)-1,auto_cor);
                title('Central Moment');
                xlabel('Time Delay');
                grid on
                hs(1) = h;
            end
            first_deriv = auto_cor(2:end) - auto_cor(1:end-1);
            if ismember('First Derivative',cor_ops)
                h = figure();
                plot(first_deriv);
                title('First Derivative');
                xlabel('Time Delay');
                grid on
                hs(2) = h;
            end
            if ismember('Second Derivative', cor_ops)
                h = figure();
                plot(first_deriv(2:end) - first_deriv(1:end-1));
                title('Second Derivative');
                xlabel('Time Delay');
                grid on
                hs(3) = h;
            end
            
            
            if app.GuessElongationTimeCheckBox.Value
                learn1(app, auto_cor);
            end
            if app.GuessElongationTimeCheckBox_2.Value
                learn2(app, auto_cor,hs);
            end
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            elong_time = app.ElongationTimesEditField.Value;
            time_res = app.TimeResolutionsEditField.Value;
            points_per_trace = app.LengthofTracespointsEditField.Value;
            num_traces = app.NumberofTracesEditField.Value;
            trans_mat = app.TransMat.Data;
            num_states = app.NumberofStatesEditField.Value;
            fluo_per_rna = app.fluoRNAEditField.Value;
            rna_per_sec = app.Rates.Data;
            init_dist = app.InitProbs.Data;
            rise_time = app.RiseTimesEditField.Value;
            noise = app.NoiseEditField.Value;
            addpath('src/');
            addpath('src/utilities/');
            elong_ops = app.ElongationDynamicsChoiceListBox.Value;
            if ismember('Pauses',elong_ops)
                pause_locs = app.PauseLocs.Data;
                mean_pauses = app.MeanPauses.Data;
                sd_pauses = app.SDPauses.Data;
                elong_time = app.ElongationTimewoPausesEditField.Value;
                rise_time = app.RiseTimeLocEditField_2.Value;
                traces = gen_data_pauses(elong_time, pause_locs,mean_pauses,sd_pauses, ...
                                  time_res, points_per_trace, num_traces, num_states, ...
                                  trans_mat, rna_per_sec, fluo_per_rna, rise_time, init_dist, ...
                                  noise);
            elseif ismember('Diff Len',elong_ops)
                if strcmp(app.DropDown_2.Value,'Gaussian')
                    elong_vars = {'Gaussian',app.MeanEditField_2.Value,app.StandardDeviationEditField_2.Value};
                else
                    elong_vars = {'Uniform',app.BeginEditField_2.Value,app.EndEditField_2.Value,app.StepEditField_4.Value};
                end
                rise_time = app.RiseTimeEditField.Value;
                traces = gen_data_var_len(elong_vars, time_res, points_per_trace, num_traces, num_states, ...
                                  trans_mat, rna_per_sec, fluo_per_rna, rise_time, init_dist, ...
                                  noise);
            elseif ismember('Diff Rates',elong_ops)
                if strcmp(app.DropDown.Value,'Gaussian')
                    elong_vars = {'Gaussian',app.MeanEditField.Value,app.StandardDeviationEditField.Value};
                else
                    elong_vars = {'Uniform',app.BeginEditField.Value,app.EndEditField.Value,app.StepEditField_3.Value};
                end
                rise_time = app.RiseTimeLocEditField.Value;
                traces = gen_data_rate_spread(elong_vars, time_res, points_per_trace, num_traces, num_states, ...
                                  trans_mat, rna_per_sec, fluo_per_rna, rise_time, init_dist, ...
                                  noise);
            else
                traces = gen_data(elong_time, time_res, points_per_trace, num_traces, num_states, ...
                                  trans_mat, rna_per_sec, fluo_per_rna, rise_time, init_dist, ...
                                  noise);
            end
            
            % mess with traces
            traces  = messWithTraces(app, traces);
            
            % make traces diff traces instead
            if app.UseDiffTracesCheckBox.Value
                new_traces = cell(1, length(traces));
                for i = 1:length(traces)
                    new_traces{i} = traces{i}(2:end) - traces{i}(1:end-1);
                end
                traces = new_traces;
            end
            
            cor_ops = app.GenerateListBox.Value;
            derivs = [];
            if ismember('Autocorrelation',cor_ops)
                derivs = [derivs 0];
            end
            if ismember('First Derivative',cor_ops)
                derivs = [derivs 1];
            end
            if ismember('Second Derivative', cor_ops)
                derivs = [derivs 2];
            end
            if ismember('Third Derivative', cor_ops)
                derivs = [derivs 3];
            end
            max_delay = app.MaxDelayEditField.Value;
            cut = app.CutEditField.Value;
            bootstraps = app.BootstrapsCheckBox.Value;
            hs = gen_auto_cor(traces, derivs, bootstraps, max_delay,cut);
            auto_cor = auto_corr_m_calc_norm(traces, max_delay);
            
            % plots expected autocorrelation on same plot as simulated autocorrelation
            if app.PlotExpectationValueCheckBox.Value
                
                [a_arr, b_arr] = decompose_matrix(app.TransMat.Data, app.Rates.Data);
                mat_prop_dat = zeros(app.NumberofStatesEditField.Value, 2);
                mat_prop_dat(:, 1) = a_arr';
                mat_prop_dat(:, 2) = b_arr';
                app.MatrixProperties.Data = mat_prop_dat;
                if ismember('Diff Len', elong_ops)
                    if strcmp(elong_vars{1}, 'Gaussian')
                        elong_spread = elong_vars{3};
                        elong_time = elong_vars{2};
                    else
                        elong_time = (elong_vars{2} + elong_vars{3}) / 2;
                        elong_spread = (elong_vars{3} - elong_vars{2}) * 2 / 3;
                    end
                    plot_expected_auto_cor(hs, elong_time / time_res, rise_time / elong_time, ...
                        app.MatrixProperties.Data(:,1)' * time_res, ...
                        -app.MatrixProperties.Data(:,2)' * time_res, max_delay, true, elong_spread / time_res, 1);
                else
                    plot_expected_auto_cor(hs, elong_time / time_res, rise_time / elong_time, ...
                        app.MatrixProperties.Data(:,1)' * time_res, ...
                        -app.MatrixProperties.Data(:,2)' * time_res, max_delay, false);
                end
            end
            
            % estimates elongation/rise time using method 1 if box is checked
            if app.GuessElongationTimeCheckBox.Value             
                learn1(app, auto_cor);
            end
            
            % estimates elongation time using method 2 if the box is checked
            if app.GuessElongationTimeCheckBox_2.Value
                learn2(app, auto_cor, hs);
            end
            
            % shows where elongation time and rise time are
            if app.ShowElongandRiseTimesCheckBox.Value
                plot_elong_and_alph(hs, elong_time / time_res, rise_time / time_res);
            end
            
        end

        % Value changed function: NumberofStatesEditField_2
        function NumberofStatesEditField_2ValueChanged(app, event)
            value = app.NumberofStatesEditField_2.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.TransMatTest.ColumnName = cols;
            app.TransMatTest.Data = abs(normrnd(0,.1,[value,value]));
            for v = 1:value
                app.TransMatTest.Data(v,v) = 0;
                app.TransMatTest.Data(v,v) = -sum(app.TransMatTest.Data(:,v));
            end
            
            %resets emission rates table
            app.RatesTest.ColumnName = cols;
            dat = random('uniform',0,1,[1,value-1]);
            app.RatesTest.Data = [.0001, sort(dat)];
            
            %resets intial probability distribution table
            app.InitProbsTest.ColumnName = cols;
            dat = random('uniform',0,1,[1,value]);
            dat = dat / sum(dat);
            app.InitProbsTest.Data = dat;
        end

        % Value changed function: NumberofPausesEditField
        function NumberofPausesEditFieldValueChanged(app, event)
            value = app.NumberofPausesEditField.Value;
            cols = cell(1,value);
            for i = 1:value
                cols{i} = ['Pause ' int2str(i)];
            end
            mean_pauses = ones(1,value) * 20;
            app.MeanPauses.Data = mean_pauses;
            app.MeanPauses.ColumnName = cols;
            sd_pauses = ones(1,value) * 5;
            app.SDPauses.Data = sd_pauses;
            app.SDPauses.ColumnName = cols;
            pause_locs = .1:.8 / value:.9;
            pause_locs = pause_locs(1:value);
            app.PauseLocs.Data = pause_locs;
            app.PauseLocs.ColumnName = cols;
        end

        % Cell edit callback: InitProbs
        function InitProbsCellEdit(app, event)
            %says what the new a and b values are for the new matrix
            [a_arr, b_arr] = decompose_matrix(app.TransMat.Data, app.Rates.Data);
            mat_prop_dat = zeros(app.NumberofStatesEditField.Value, 2);
            mat_prop_dat(:, 1) = a_arr';
            mat_prop_dat(:, 2) = b_arr';
            app.MatrixProperties.Data = mat_prop_dat;
        end

        % Value changed function: NumberofEigenvaluesEditField
        function NumberofEigenvaluesEditFieldValueChanged(app, event)
            value = app.NumberofEigenvaluesEditField.Value;
            a_avg = (app.amaximumEditField.Value + app.aminimumEditField.Value) / 2;
            b_avg = (app.bmaximumEditField.Value + app.bminimumEditField.Value) / 2;
            app.DynamicsGuesses.Data = [a_avg * ones(1, value); b_avg * ones(1, value)]';           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenerateDataTab = uitab(app.TabGroup);
            app.GenerateDataTab.Title = 'Generate Data';
            app.TransMat = uitable(app.GenerateDataTab);
            app.TransMat.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.TransMat.RowName = {};
            app.TransMat.ColumnEditable = true;
            app.TransitionRateMatrixflowsLabel = uilabel(app.GenerateDataTab);
            app.TransitionRateMatrixflowsLabel.Text = 'Transition Rate Matrix (flow/s)';
            app.LengthofTracessLabel = uilabel(app.GenerateDataTab);
            app.LengthofTracessLabel.Text = 'Length of Traces (s)';
            app.LengthTraceNum = uilabel(app.GenerateDataTab);
            app.LengthTraceNum.Text = '2000';
            app.Rates = uitable(app.GenerateDataTab);
            app.Rates.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.Rates.RowName = {};
            app.Rates.ColumnEditable = true;
            app.EmissionRatesRNAsLabel = uilabel(app.GenerateDataTab);
            app.EmissionRatesRNAsLabel.Text = 'Emission Rates (RNA/s)';
            app.BootstrapsCheckBox = uicheckbox(app.GenerateDataTab);
            app.BootstrapsCheckBox.Text = 'Bootstraps';
            app.InitProbs = uitable(app.GenerateDataTab);
            app.InitProbs.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.InitProbs.RowName = {};
            app.InitProbs.ColumnEditable = true;
            app.InitProbs.CellEditCallback = createCallbackFcn(app, @InitProbsCellEdit, true);
            app.InitialProbabilityDistributionLabel = uilabel(app.GenerateDataTab);
            app.InitialProbabilityDistributionLabel.Text = 'Initial Probability Distribution';
            app.TimeResolutionsEditFieldLabel = uilabel(app.GenerateDataTab);
            app.TimeResolutionsEditFieldLabel.Text = 'Time Resolution (s)';
            app.TimeResolutionsEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.TimeResolutionsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeResolutionsEditFieldValueChanged, true);
            app.TimeResolutionsEditField.Value = 20;
            app.LengthofTracespointsEditFieldLabel = uilabel(app.GenerateDataTab);
            app.LengthofTracespointsEditFieldLabel.Text = 'Length of Traces (points)';
            app.LengthofTracespointsEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.LengthofTracespointsEditField.Limits = [1 Inf];
            app.LengthofTracespointsEditField.ValueDisplayFormat = '%.0f';
            app.LengthofTracespointsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofTracespointsEditFieldValueChanged, true);
            app.LengthofTracespointsEditField.Value = 100;
            app.NumberofTracesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NumberofTracesEditFieldLabel.Text = 'Number of Traces ';
            app.NumberofTracesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NumberofTracesEditField.Limits = [1 Inf];
            app.NumberofTracesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTracesEditField.Value = 50;
            app.NumberofStatesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States';
            app.NumberofStatesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NumberofStatesEditField.Limits = [0 Inf];
            app.NumberofStatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditFieldValueChanged, true);
            app.NumberofStatesEditField.Value = 3;
            app.fluoRNAEditFieldLabel = uilabel(app.GenerateDataTab);
            app.fluoRNAEditFieldLabel.Text = 'fluo/RNA';
            app.fluoRNAEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.fluoRNAEditField.Limits = [0 Inf];
            app.fluoRNAEditField.Value = 350;
            app.GenerateListBoxLabel = uilabel(app.GenerateDataTab);
            app.GenerateListBoxLabel.Text = 'Generate';
            app.GenerateListBox = uilistbox(app.GenerateDataTab);
            app.GenerateListBox.Items = {'Autocorrelation', 'First Derivative', 'Second Derivative', 'Third Derivative'};
            app.GenerateListBox.Multiselect = 'on';
            app.GenerateListBox.Value = {'Autocorrelation'};
            app.NoiseEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.MaxDelayEditFieldLabel = uilabel(app.GenerateDataTab);
            app.MaxDelayEditFieldLabel.Text = 'Max Delay';
            app.MaxDelayEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.MaxDelayEditField.Limits = [1 Inf];
            app.MaxDelayEditField.Value = 20;
            app.CutEditFieldLabel = uilabel(app.GenerateDataTab);
            app.CutEditFieldLabel.Text = 'Cut';
            app.CutEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.CutEditField.Limits = [0 Inf];
            app.TabGroup2 = uitabgroup(app.GenerateDataTab);
            app.ConstantTab = uitab(app.TabGroup2);
            app.ConstantTab.Title = 'Constant';
            app.ElongationTimesEditFieldLabel = uilabel(app.ConstantTab);
            app.ElongationTimesEditFieldLabel.Text = 'Elongation Time (s)';
            app.ElongationTimesEditField = uieditfield(app.ConstantTab, 'numeric');
            app.ElongationTimesEditField.Limits = [0 Inf];
            app.ElongationTimesEditField.Value = 160;
            app.RiseTimesEditFieldLabel = uilabel(app.ConstantTab);
            app.RiseTimesEditFieldLabel.Text = 'Rise Time (s)';
            app.RiseTimesEditField = uieditfield(app.ConstantTab, 'numeric');
            app.RiseTimesEditField.Limits = [0 Inf];
            app.RiseTimesEditField.Value = 40;
            app.DiffRatesTab = uitab(app.TabGroup2);
            app.DiffRatesTab.Title = 'Diff Rates';
            app.DropDownLabel = uilabel(app.DiffRatesTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.DiffRatesTab);
            app.DropDown.Items = {'Gaussian', 'Uniform'};
            app.DropDown.Value = 'Gaussian';
            app.StandardDeviationEditFieldLabel = uilabel(app.DiffRatesTab);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEditField = uieditfield(app.DiffRatesTab, 'numeric');
            app.StandardDeviationEditField.Value = 20;
            app.MeanEditFieldLabel = uilabel(app.DiffRatesTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.DiffRatesTab, 'numeric');
            app.MeanEditField.Value = 160;
            app.GaussianLabel = uilabel(app.DiffRatesTab);
            app.GaussianLabel.Text = 'Gaussian';
            app.UniformLabel = uilabel(app.DiffRatesTab);
            app.UniformLabel.Text = 'Uniform';
            app.BeginEditFieldLabel = uilabel(app.DiffRatesTab);
            app.BeginEditFieldLabel.Text = 'Begin';
            app.BeginEditField = uieditfield(app.DiffRatesTab, 'numeric');
            app.BeginEditField.Value = 120;
            app.EndEditFieldLabel = uilabel(app.DiffRatesTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.DiffRatesTab, 'numeric');
            app.EndEditField.Value = 180;
            app.StepEditField_3Label = uilabel(app.DiffRatesTab);
            app.StepEditField_3Label.Text = 'Step';
            app.StepEditField_3 = uieditfield(app.DiffRatesTab, 'numeric');
            app.StepEditField_3.Value = 20;
            app.RiseTimeLocEditFieldLabel = uilabel(app.DiffRatesTab);
            app.RiseTimeLocEditFieldLabel.Text = 'Rise Time  Loc';
            app.RiseTimeLocEditField = uieditfield(app.DiffRatesTab, 'numeric');
            app.RiseTimeLocEditField.Value = 0.4;
            app.DiffLenTab = uitab(app.TabGroup2);
            app.DiffLenTab.Title = 'Diff Len';
            app.GaussianLabel_2 = uilabel(app.DiffLenTab);
            app.GaussianLabel_2.Text = 'Gaussian';
            app.UniformLabel_2 = uilabel(app.DiffLenTab);
            app.UniformLabel_2.Text = 'Uniform';
            app.DropDown_2Label = uilabel(app.DiffLenTab);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.DiffLenTab);
            app.DropDown_2.Items = {'Gaussian', 'Uniform'};
            app.DropDown_2.Value = 'Gaussian';
            app.StandardDeviationEditField_2Label = uilabel(app.DiffLenTab);
            app.StandardDeviationEditField_2Label.Text = 'Standard Deviation';
            app.StandardDeviationEditField_2 = uieditfield(app.DiffLenTab, 'numeric');
            app.StandardDeviationEditField_2.Value = 20;
            app.MeanEditField_2Label = uilabel(app.DiffLenTab);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.DiffLenTab, 'numeric');
            app.MeanEditField_2.Value = 160;
            app.BeginEditField_2Label = uilabel(app.DiffLenTab);
            app.BeginEditField_2Label.Text = 'Begin';
            app.BeginEditField_2 = uieditfield(app.DiffLenTab, 'numeric');
            app.BeginEditField_2.Value = 120;
            app.EndEditField_2Label = uilabel(app.DiffLenTab);
            app.EndEditField_2Label.Text = 'End';
            app.EndEditField_2 = uieditfield(app.DiffLenTab, 'numeric');
            app.EndEditField_2.Value = 180;
            app.StepEditField_4Label = uilabel(app.DiffLenTab);
            app.StepEditField_4Label.Text = 'Step';
            app.StepEditField_4 = uieditfield(app.DiffLenTab, 'numeric');
            app.StepEditField_4.Value = 10;
            app.RiseTimeEditFieldLabel = uilabel(app.DiffLenTab);
            app.RiseTimeEditFieldLabel.Text = 'Rise Time';
            app.RiseTimeEditField = uieditfield(app.DiffLenTab, 'numeric');
            app.RiseTimeEditField.Value = 40;
            app.PausesTab = uitab(app.TabGroup2);
            app.PausesTab.Title = 'Pauses';
            app.ElongationTimewoPausesEditFieldLabel = uilabel(app.PausesTab);
            app.ElongationTimewoPausesEditFieldLabel.Text = 'Elongation Time w/o Pauses';
            app.ElongationTimewoPausesEditField = uieditfield(app.PausesTab, 'numeric');
            app.ElongationTimewoPausesEditField.Value = 160;
            app.RiseTimeLocEditField_2Label = uilabel(app.PausesTab);
            app.RiseTimeLocEditField_2Label.Text = 'Rise Time Loc';
            app.RiseTimeLocEditField_2 = uieditfield(app.PausesTab, 'numeric');
            app.RiseTimeLocEditField_2.Value = 0.3;
            app.NumberofPausesEditFieldLabel = uilabel(app.PausesTab);
            app.NumberofPausesEditFieldLabel.Text = 'Number of Pauses';
            app.NumberofPausesEditField = uieditfield(app.PausesTab, 'numeric');
            app.NumberofPausesEditField.RoundFractionalValues = 'on';
            app.NumberofPausesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPausesEditFieldValueChanged, true);
            app.NumberofPausesEditField.Value = 1;
            app.PauseLocs = uitable(app.PausesTab);
            app.PauseLocs.ColumnName = {'Pause 1'};
            app.PauseLocs.RowName = {};
            app.PauseLocs.ColumnEditable = true;
            app.MeanPauses = uitable(app.PausesTab);
            app.MeanPauses.ColumnName = {'Pause 1'};
            app.MeanPauses.RowName = {};
            app.MeanPauses.ColumnEditable = true;
            app.SDPauses = uitable(app.PausesTab);
            app.SDPauses.ColumnName = {'Pause 1'};
            app.SDPauses.RowName = {};
            app.SDPauses.ColumnEditable = true;
            app.PauseLocationsLabel = uilabel(app.PausesTab);
            app.PauseLocationsLabel.Text = 'Pause Locations';
            app.MeanPauseTimesLabel = uilabel(app.PausesTab);
            app.MeanPauseTimesLabel.Text = 'Mean Pause Times';
            app.SDPauseTimesLabel = uilabel(app.PausesTab);
            app.SDPauseTimesLabel.Text = 'SD Pause Times';
            app.ElongationDynamicsLabel = uilabel(app.GenerateDataTab);
            app.ElongationDynamicsLabel.Text = 'Elongation Dynamics';
            app.ElongationDynamicsChoiceListBoxLabel = uilabel(app.GenerateDataTab);
            app.ElongationDynamicsChoiceListBoxLabel.Text = 'Elongation Dynamics Choice';
            app.ElongationDynamicsChoiceListBox = uilistbox(app.GenerateDataTab);
            app.ElongationDynamicsChoiceListBox.Items = {'Constant', 'Diff Rates', 'Diff Len', 'Pauses'};
            app.ElongationDynamicsChoiceListBox.Multiselect = 'on';
            app.ElongationDynamicsChoiceListBox.Value = {'Constant'};
            app.MatrixProperties = uitable(app.GenerateDataTab);
            app.MatrixProperties.ColumnName = {'a'; 'b'};
            app.MatrixProperties.RowName = {};
            app.PlotExpectationValueCheckBox = uicheckbox(app.GenerateDataTab);
            app.PlotExpectationValueCheckBox.Text = 'Plot Expectation Value';
            app.UseDiffTracesCheckBox = uicheckbox(app.GenerateDataTab);
            app.UseDiffTracesCheckBox.Text = 'Use Diff Traces';
            app.ShowElongandRiseTimesCheckBox = uicheckbox(app.GenerateDataTab);
            app.ShowElongandRiseTimesCheckBox.Text = 'Show Elong and Rise Times';
            app.Learning1Tab = uitab(app.TabGroup);
            app.Learning1Tab.Title = 'Learning 1';
            app.GuessElongationTimeCheckBox = uicheckbox(app.Learning1Tab);
            app.GuessElongationTimeCheckBox.Text = 'Guess Elongation Time';
            app.UseRealParamsCheckBox = uicheckbox(app.Learning1Tab);
            app.UseRealParamsCheckBox.Text = 'Use Real Params';
            app.ElongationTimeGuessesLabel = uilabel(app.Learning1Tab);
            app.ElongationTimeGuessesLabel.Text = 'Elongation Time Guesses';
            app.MinimumEditFieldLabel = uilabel(app.Learning1Tab);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.MinimumEditField.Limits = [0 Inf];
            app.MinimumEditField.Value = 120;
            app.MaximumEditFieldLabel = uilabel(app.Learning1Tab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.MaximumEditField.Limits = [0 Inf];
            app.MaximumEditField.Value = 200;
            app.StepEditFieldLabel = uilabel(app.Learning1Tab);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.Value = 10;
            app.RiseTimeGuessesLabel = uilabel(app.Learning1Tab);
            app.RiseTimeGuessesLabel.Text = 'Rise Time Guesses';
            app.MinimumEditField_2Label = uilabel(app.Learning1Tab);
            app.MinimumEditField_2Label.Text = 'Minimum';
            app.MinimumEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.MinimumEditField_2.Limits = [0 Inf];
            app.MaximumEditField_2Label = uilabel(app.Learning1Tab);
            app.MaximumEditField_2Label.Text = 'Maximum';
            app.MaximumEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.MaximumEditField_2.Limits = [0 Inf];
            app.MaximumEditField_2.Value = 40;
            app.StepEditField_2Label = uilabel(app.Learning1Tab);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.StepEditField_2.Value = 5;
            app.NumberofStatesEditField_2Label = uilabel(app.Learning1Tab);
            app.NumberofStatesEditField_2Label.Text = 'Number of States';
            app.NumberofStatesEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.NumberofStatesEditField_2.Limits = [0 Inf];
            app.NumberofStatesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditField_2ValueChanged, true);
            app.NumberofStatesEditField_2.Value = 3;
            app.TransMatTest = uitable(app.Learning1Tab);
            app.TransMatTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.TransMatTest.RowName = {};
            app.TransMatTest.ColumnEditable = true;
            app.TransitionRateMatrixflowsLabel_2 = uilabel(app.Learning1Tab);
            app.TransitionRateMatrixflowsLabel_2.Text = 'Transition Rate Matrix (flow/s)';
            app.fluoRNAEditField_2Label = uilabel(app.Learning1Tab);
            app.fluoRNAEditField_2Label.Text = 'fluo/RNA';
            app.fluoRNAEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.fluoRNAEditField_2.Limits = [0 Inf];
            app.fluoRNAEditField_2.Value = 350;
            app.RatesTest = uitable(app.Learning1Tab);
            app.RatesTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.RatesTest.RowName = {};
            app.RatesTest.ColumnEditable = true;
            app.EmissionRatesRNAsLabel_2 = uilabel(app.Learning1Tab);
            app.EmissionRatesRNAsLabel_2.Text = 'Emission Rates (RNA/s)';
            app.InitProbsTest = uitable(app.Learning1Tab);
            app.InitProbsTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.InitProbsTest.RowName = {};
            app.InitProbsTest.ColumnEditable = true;
            app.InitialProbabilityDistributionLabel_2 = uilabel(app.Learning1Tab);
            app.InitialProbabilityDistributionLabel_2.Text = 'Initial Probability Distribution';
            app.ElongationTimeEstimateEditFieldLabel = uilabel(app.Learning1Tab);
            app.ElongationTimeEstimateEditFieldLabel.Text = 'Elongation Time Estimate';
            app.ElongationTimeEstimateEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.ElongationTimeEstimateEditField.Editable = 'off';
            app.RiseTimeEstimateEditFieldLabel = uilabel(app.Learning1Tab);
            app.RiseTimeEstimateEditFieldLabel.Text = 'Rise Time Estimate';
            app.RiseTimeEstimateEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.RiseTimeEstimateEditField.Editable = 'off';
            app.UITable = uitable(app.Learning1Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ErrorsLabel = uilabel(app.Learning1Tab);
            app.ErrorsLabel.Text = 'Errors';
            app.Learning2Tab = uitab(app.TabGroup);
            app.Learning2Tab.Title = 'Learning 2';
            app.GuessElongationTimeCheckBox_2 = uicheckbox(app.Learning2Tab);
            app.GuessElongationTimeCheckBox_2.Text = 'Guess Elongation Time';
            app.Equationacbx1aelong_termelongationtimerisetimeLabel = uilabel(app.Learning2Tab);
            app.Equationacbx1aelong_termelongationtimerisetimeLabel.Text = 'Equation: poisson term(elongation time,rise time) + a * dynamics term(elongation time, rise time, b)';
            app.InitialGuessesLabel = uilabel(app.Learning2Tab);
            app.InitialGuessesLabel.Text = 'Initial Guesses';
            app.aminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.aminimumEditFieldLabel.Text = 'a minimum';
            app.aminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.aminimumEditField.Limits = [0 Inf];
            app.bminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.bminimumEditFieldLabel.Text = 'b minimum';
            app.bminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.bminimumEditField.Limits = [0 Inf];
            app.bminimumEditField.Value = 0.01;
            app.bmaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.bmaximumEditFieldLabel.Text = 'b maximum';
            app.bmaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.bmaximumEditField.Limits = [0 Inf];
            app.bmaximumEditField.Value = 5;
            app.ResultsLabel = uilabel(app.Learning2Tab);
            app.ResultsLabel.Text = 'Results';
            app.elongationtimeEditField_2Label = uilabel(app.Learning2Tab);
            app.elongationtimeEditField_2Label.Text = 'elongation time';
            app.elongationtimeEditField_2 = uieditfield(app.Learning2Tab, 'numeric');
            app.elongationtimeEditField_2.Editable = 'off';
            app.risetimeEditField_2Label = uilabel(app.Learning2Tab);
            app.risetimeEditField_2Label.Text = 'rise time';
            app.risetimeEditField_2 = uieditfield(app.Learning2Tab, 'numeric');
            app.risetimeEditField_2.Editable = 'off';
            app.amaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.amaximumEditFieldLabel.Text = 'a maximum';
            app.amaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.amaximumEditField.Limits = [0 Inf];
            app.amaximumEditField.Value = 100;
            app.elongationtimeminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.elongationtimeminimumEditFieldLabel.Text = 'elongation time minimum';
            app.elongationtimeminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.elongationtimeminimumEditField.Limits = [0 Inf];
            app.elongationtimeminimumEditField.Value = 4;
            app.elongationtimemaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.elongationtimemaximumEditFieldLabel.Text = 'elongation time maximum';
            app.elongationtimemaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.elongationtimemaximumEditField.Limits = [0 Inf];
            app.elongationtimemaximumEditField.Value = 12;
            app.risetimepercentageminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.risetimepercentageminimumEditFieldLabel.Text = 'rise time percentage minimum';
            app.risetimepercentageminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.risetimepercentageminimumEditField.Limits = [0 1];
            app.risetimepercentageminimumEditField.Value = 0.01;
            app.risetimepercentagemaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.risetimepercentagemaximumEditFieldLabel.Text = 'rise time percentage maximum';
            app.risetimepercentagemaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.risetimepercentagemaximumEditField.Limits = [0 1];
            app.risetimepercentagemaximumEditField.Value = 1;
            app.NumberofEigenvaluesEditFieldLabel = uilabel(app.Learning2Tab);
            app.NumberofEigenvaluesEditFieldLabel.Text = 'Number of Eigenvalues';
            app.NumberofEigenvaluesEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.NumberofEigenvaluesEditField.Limits = [1 Inf];
            app.NumberofEigenvaluesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofEigenvaluesEditFieldValueChanged, true);
            app.NumberofEigenvaluesEditField.Value = 1;
            app.UITable2 = uitable(app.Learning2Tab);
            app.UITable2.ColumnName = {'a'; 'b'};
            app.UITable2.RowName = {};
            app.TypeofFittingButtonGroup = uibuttongroup(app.Learning2Tab);
            app.TypeofFittingButtonGroup.Title = 'Type of Fitting';
            app.NormalFittingButton = uiradiobutton(app.TypeofFittingButtonGroup);
            app.NormalFittingButton.Text = 'Normal Fitting';
            app.NormalFittingButton.Value = true;
            app.GaussFittingButton = uiradiobutton(app.TypeofFittingButtonGroup);
            app.GaussFittingButton.Text = 'Gauss Fitting';
            app.ElongationDistributionPars = uitable(app.Learning2Tab);
            app.ElongationDistributionPars.ColumnName = {'Par1 Min'; 'Par1 Max'; 'Par2 Min'; 'Par2 Max'};
            app.ElongationDistributionPars.RowName = {};
            app.ElongationDistributionPars.ColumnEditable = true;
            app.ExtraParameterResultsLabel = uilabel(app.Learning2Tab);
            app.ExtraParameterResultsLabel.Text = 'Extra Parameter Results';
            app.ExtraParamResults = uitable(app.Learning2Tab);
            app.ExtraParamResults.ColumnName = {'Par1'; 'Par2'};
            app.ExtraParamResults.RowName = {};
            app.risetimepercentageinitialEditFieldLabel = uilabel(app.Learning2Tab);
            app.risetimepercentageinitialEditFieldLabel.Text = 'rise time percentage initial';
            app.RiseTimeGuess = uieditfield(app.Learning2Tab, 'numeric');
            app.RiseTimeGuess.Limits = [0 1];
            app.RiseTimeGuess.Value = 0.25;
            app.elongationtimeinitialEditFieldLabel = uilabel(app.Learning2Tab);
            app.elongationtimeinitialEditFieldLabel.Text = 'elongation time initial';
            app.ElongationTimeGuess = uieditfield(app.Learning2Tab, 'numeric');
            app.ElongationTimeGuess.Limits = [0 Inf];
            app.ElongationTimeGuess.Value = 8;
            app.DynamicsGuesses = uitable(app.Learning2Tab);
            app.DynamicsGuesses.ColumnName = {'a initial'; 'b initial'};
            app.DynamicsGuesses.RowName = {};
            app.DynamicsGuesses.ColumnEditable = true;
            app.ExtraParamsGuesses = uitable(app.Learning2Tab);
            app.ExtraParamsGuesses.ColumnName = {'Par1 Guess'; 'Par2 Guess'};
            app.ExtraParamsGuesses.RowName = {};
            app.ExtraParamsGuesses.ColumnEditable = true;
            app.UseMCMCModelCheckBox = uicheckbox(app.Learning2Tab);
            app.UseMCMCModelCheckBox.Text = 'Use MCMC Model';
            app.MakeHeatMapCheckBox = uicheckbox(app.Learning2Tab);
            app.MakeHeatMapCheckBox.Text = 'Make Heat Map';
            app.MesswithTracesTab = uitab(app.TabGroup);
            app.MesswithTracesTab.Title = 'Mess with Traces';
            app.Extend0sCheckBox = uicheckbox(app.MesswithTracesTab);
            app.Extend0sCheckBox.Text = 'Extend 0s';
            app.MeanExtendedEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.MeanExtendedEditFieldLabel.Text = 'Mean Extended';
            app.MeanExtendedEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.MeanExtendedEditField.Value = 10;
            app.DropRandomLowPointsCheckBox = uicheckbox(app.MesswithTracesTab);
            app.DropRandomLowPointsCheckBox.Text = 'Drop Random Low Points';
            app.MaxValueDroppedEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.MaxValueDroppedEditFieldLabel.Text = 'Max Value Dropped';
            app.MaxValueDroppedEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.MaxValueDroppedEditField.Limits = [0 Inf];
            app.MaxValueDroppedEditField.Value = 700;
            app.ProbabilityDroppedEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.ProbabilityDroppedEditFieldLabel.Text = 'Probability Dropped';
            app.ProbabilityDroppedEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.ProbabilityDroppedEditField.Limits = [0 1];
            app.ProbabilityDroppedEditField.Value = 0.25;
            app.StdExtendedEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.StdExtendedEditFieldLabel.Text = 'Std Extended';
            app.StdExtendedEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.StdExtendedEditField.Limits = [0 Inf];
            app.StdExtendedEditField.Value = 30;
            app.EliminateExtraZerosCheckBox = uicheckbox(app.MesswithTracesTab);
            app.EliminateExtraZerosCheckBox.Text = 'Eliminate Extra Zeros';
            app.MaximumConsecutiveZerosEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.MaximumConsecutiveZerosEditFieldLabel.Text = 'Maximum Consecutive Zeros';
            app.MaximumConsecutiveZerosEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.MaximumConsecutiveZerosEditField.Value = 5;
            app.DivideTracesWithZerosCheckBox = uicheckbox(app.MesswithTracesTab);
            app.DivideTracesWithZerosCheckBox.Text = 'Divide Traces With Zeros';
            app.MinimumConsecutiveZerosEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.MinimumConsecutiveZerosEditFieldLabel.Text = 'Minimum Consecutive Zeros';
            app.MinimumConsecutiveZerosEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.MinimumConsecutiveZerosEditField.Value = 5;
            app.MinimumZeroValEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.MinimumZeroValEditFieldLabel.Text = 'Minimum Zero Val';
            app.MinimumZeroValEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.MinimumZeroValEditField.Value = 50;
            app.MinTraceLengthEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.MinTraceLengthEditFieldLabel.Text = 'Min Trace Length';
            app.MinTraceLengthEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.MinTraceLengthEditField.Value = 100;
            app.CutLengthEditFieldLabel = uilabel(app.MesswithTracesTab);
            app.CutLengthEditFieldLabel.Text = 'Cut Length';
            app.CutLengthEditField = uieditfield(app.MesswithTracesTab, 'numeric');
            app.GeneratePlotsButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.LoadPlotsButton = uibutton(app.UIFigure, 'push');
            app.LoadPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotsButtonPushed, true);
            app.LoadPlotsButton.Text = 'Load Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auto_corr_gen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end